classdef pacejka_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PacejkaTireModelUIFigure  matlab.ui.Figure
        FxPlot                    matlab.ui.control.UIAxes
        SlipAngledegSliderLabel   matlab.ui.control.Label
        SlipAngledegSlider        matlab.ui.control.Slider
        ResetButton               matlab.ui.control.Button
        SlipRatioSliderLabel      matlab.ui.control.Label
        SlipRatioSlider           matlab.ui.control.Slider
        UndoButton                matlab.ui.control.Button
        HoldButton                matlab.ui.control.StateButton
        Fz0NSliderLabel           matlab.ui.control.Label
        Fz0NSlider                matlab.ui.control.Slider
        CamberdegSliderLabel      matlab.ui.control.Label
        CamberdegSlider           matlab.ui.control.Slider
        FzNSlider_2Label          matlab.ui.control.Label
        FzNSlider                 matlab.ui.control.Slider
        TabGroup                  matlab.ui.container.TabGroup
        Fx0Tab                    matlab.ui.container.Tab
        PCx1SliderLabel           matlab.ui.control.Label
        PCx1Slider                matlab.ui.control.Slider
        PDx1SliderLabel           matlab.ui.control.Label
        PDx1Slider                matlab.ui.control.Slider
        PDx2SliderLabel           matlab.ui.control.Label
        PDx2Slider                matlab.ui.control.Slider
        PDx3SliderLabel           matlab.ui.control.Label
        PDx3Slider                matlab.ui.control.Slider
        PEx1SliderLabel           matlab.ui.control.Label
        PEx1Slider                matlab.ui.control.Slider
        PEx2SliderLabel           matlab.ui.control.Label
        PEx2Slider                matlab.ui.control.Slider
        PEx3SliderLabel           matlab.ui.control.Label
        PEx3Slider                matlab.ui.control.Slider
        PKx1SliderLabel           matlab.ui.control.Label
        PKx1Slider                matlab.ui.control.Slider
        Ppx1SliderLabel           matlab.ui.control.Label
        Ppx1Slider                matlab.ui.control.Slider
        PEx4SliderLabel           matlab.ui.control.Label
        PEx4Slider                matlab.ui.control.Slider
        PKx2SliderLabel           matlab.ui.control.Label
        PKx2Slider                matlab.ui.control.Slider
        PKx3SliderLabel           matlab.ui.control.Label
        PKx3Slider                matlab.ui.control.Slider
        PHx1Slider_5Label         matlab.ui.control.Label
        PHx1Slider                matlab.ui.control.Slider
        PHx2SliderLabel           matlab.ui.control.Label
        PHx2Slider                matlab.ui.control.Slider
        PVx1SliderLabel           matlab.ui.control.Label
        PVx1Slider                matlab.ui.control.Slider
        PVx2SliderLabel           matlab.ui.control.Label
        PVx2Slider                matlab.ui.control.Slider
        Ppx2SliderLabel           matlab.ui.control.Label
        Ppx2Slider                matlab.ui.control.Slider
        Ppx3SliderLabel           matlab.ui.control.Label
        Ppx3Slider                matlab.ui.control.Slider
        Ppx4SliderLabel           matlab.ui.control.Label
        Ppx4Slider                matlab.ui.control.Slider
        FxTab                     matlab.ui.container.Tab
        RBx1SliderLabel           matlab.ui.control.Label
        RBx1Slider                matlab.ui.control.Slider
        RBx2SliderLabel           matlab.ui.control.Label
        RBx2Slider                matlab.ui.control.Slider
        RBx3SliderLabel           matlab.ui.control.Label
        RBx3Slider                matlab.ui.control.Slider
        RCx1SliderLabel           matlab.ui.control.Label
        RCx1Slider                matlab.ui.control.Slider
        REx1SliderLabel           matlab.ui.control.Label
        REx1Slider                matlab.ui.control.Slider
        REx2SliderLabel           matlab.ui.control.Label
        REx2Slider                matlab.ui.control.Slider
        RHx1SliderLabel           matlab.ui.control.Label
        RHx1Slider                matlab.ui.control.Slider
        Fy0Tab                    matlab.ui.container.Tab
        PCy1SliderLabel           matlab.ui.control.Label
        PCy1Slider                matlab.ui.control.Slider
        PDy1SliderLabel           matlab.ui.control.Label
        PDy1Slider                matlab.ui.control.Slider
        PDy2SliderLabel           matlab.ui.control.Label
        PDy2Slider                matlab.ui.control.Slider
        PDy3SliderLabel           matlab.ui.control.Label
        PDy3Slider                matlab.ui.control.Slider
        PEy1SliderLabel           matlab.ui.control.Label
        PEy1Slider                matlab.ui.control.Slider
        PEy2SliderLabel           matlab.ui.control.Label
        PEy2Slider                matlab.ui.control.Slider
        PEy3SliderLabel           matlab.ui.control.Label
        PEy3Slider                matlab.ui.control.Slider
        PEy4SliderLabel           matlab.ui.control.Label
        PEy4Slider                matlab.ui.control.Slider
        PEy5SliderLabel           matlab.ui.control.Label
        PEy5Slider                matlab.ui.control.Slider
        PKy1SliderLabel           matlab.ui.control.Label
        PKy1Slider                matlab.ui.control.Slider
        PKy2SliderLabel           matlab.ui.control.Label
        PKy2Slider                matlab.ui.control.Slider
        PKy3SliderLabel           matlab.ui.control.Label
        PKy3Slider                matlab.ui.control.Slider
        PKy4SliderLabel           matlab.ui.control.Label
        PKy4Slider                matlab.ui.control.Slider
        PKy5SliderLabel           matlab.ui.control.Label
        PKy5Slider                matlab.ui.control.Slider
        PKy6SliderLabel           matlab.ui.control.Label
        PKy6Slider                matlab.ui.control.Slider
        PKy7SliderLabel           matlab.ui.control.Label
        PKy7Slider                matlab.ui.control.Slider
        PHy1SliderLabel           matlab.ui.control.Label
        PHy1Slider                matlab.ui.control.Slider
        PHy2SliderLabel           matlab.ui.control.Label
        PHy2Slider                matlab.ui.control.Slider
        PVy1SliderLabel           matlab.ui.control.Label
        PVy1Slider                matlab.ui.control.Slider
        PVy2SliderLabel           matlab.ui.control.Label
        PVy2Slider                matlab.ui.control.Slider
        PVy3SliderLabel           matlab.ui.control.Label
        PVy3Slider                matlab.ui.control.Slider
        PVy4SliderLabel           matlab.ui.control.Label
        PVy4Slider                matlab.ui.control.Slider
        Ppy1SliderLabel           matlab.ui.control.Label
        Ppy1Slider                matlab.ui.control.Slider
        Ppy2SliderLabel           matlab.ui.control.Label
        Ppy2Slider                matlab.ui.control.Slider
        Ppy3SliderLabel           matlab.ui.control.Label
        Ppy3Slider                matlab.ui.control.Slider
        Ppy4SliderLabel           matlab.ui.control.Label
        Ppy4Slider                matlab.ui.control.Slider
        Ppy5SliderLabel           matlab.ui.control.Label
        Ppy5Slider                matlab.ui.control.Slider
        FyTab                     matlab.ui.container.Tab
        RBy1SliderLabel           matlab.ui.control.Label
        RBy1Slider                matlab.ui.control.Slider
        RBy2SliderLabel           matlab.ui.control.Label
        RBy2Slider                matlab.ui.control.Slider
        RBy3SliderLabel           matlab.ui.control.Label
        RBy3Slider                matlab.ui.control.Slider
        RBy4SliderLabel           matlab.ui.control.Label
        RBy4Slider                matlab.ui.control.Slider
        RCy1SliderLabel           matlab.ui.control.Label
        RCy1Slider                matlab.ui.control.Slider
        REy1SliderLabel           matlab.ui.control.Label
        REy1Slider                matlab.ui.control.Slider
        REy2SliderLabel           matlab.ui.control.Label
        REy2Slider                matlab.ui.control.Slider
        RHy1SliderLabel           matlab.ui.control.Label
        RHy1Slider                matlab.ui.control.Slider
        RHy2SliderLabel           matlab.ui.control.Label
        RHy2Slider                matlab.ui.control.Slider
        RVy1SliderLabel           matlab.ui.control.Label
        RVy1Slider                matlab.ui.control.Slider
        RVy2SliderLabel           matlab.ui.control.Label
        RVy2Slider                matlab.ui.control.Slider
        RVy3SliderLabel           matlab.ui.control.Label
        RVy3Slider                matlab.ui.control.Slider
        RVy4SliderLabel           matlab.ui.control.Label
        RVy4Slider                matlab.ui.control.Slider
        RVy5SliderLabel           matlab.ui.control.Label
        RVy5Slider                matlab.ui.control.Slider
        RVy6SliderLabel           matlab.ui.control.Label
        RVy6Slider                matlab.ui.control.Slider
        FyPlot                    matlab.ui.control.UIAxes
        PpsiSliderLabel           matlab.ui.control.Label
        PpsiSlider                matlab.ui.control.Slider
        V0kmhSliderLabel          matlab.ui.control.Label
        V0kmhSlider               matlab.ui.control.Slider
        VskmhSliderLabel          matlab.ui.control.Label
        VskmhSlider               matlab.ui.control.Slider
        P0psiSliderLabel          matlab.ui.control.Label
        P0psiSlider               matlab.ui.control.Slider
        PrintParamatersButton     matlab.ui.control.Button
        SaveModelButton           matlab.ui.control.Button
    end


    properties (Access = public)
        model = PacejkaTireModel();
        state;
        
        model_prev = PacejkaTireModel();
        state_prev;
        
        model_hold = [];
        state_hold;
    end

    methods (Access = private)
        
        function plotModel(app, plot_model, plot_state, style)
            alpha_max = deg2rad(30);
            kappa_max = 0.50;
            F_max = 3.0;
            
            kappa_plot = linspace(0, kappa_max);
            alpha_plot = linspace(0, alpha_max);
            
            [Fx, ~] = plot_model.Fx(plot_state.Fz, plot_state.P, kappa_plot, plot_state.alpha, plot_state.gamma, ...
                plot_state.Vs, plot_state.V0);
            [Fy, ~] = plot_model.Fy(plot_state.Fz, plot_state.P, plot_state.kappa, alpha_plot, plot_state.gamma, ...
                plot_state.Vs, plot_state.V0);
            
            plot(app.FxPlot, kappa_plot, Fx / plot_state.Fz, style);
            app.FxPlot.XLim = [0, kappa_max];
            app.FxPlot.YLim = [0, F_max];
            
            plot(app.FyPlot, rad2deg(alpha_plot), Fy / plot_state.Fz, style);
            app.FyPlot.XLim = [0, rad2deg(alpha_max)];
            app.FyPlot.YLim = [0, F_max];
        end
    
        function [] = replot(app)
            cla(app.FxPlot);
            cla(app.FyPlot);
            
            hold(app.FxPlot, 'on');
            hold(app.FyPlot, 'on');
            
            app.plotModel(app.model, app.state, 'b-');
            if ~isempty(app.model_hold)
                app.plotModel(app.model_hold, app.state_hold, 'r--');
            end
        end
    
        function save(app)
            app.model_prev = app.model;
            app.state_prev = app.state;
        end
    
        function reset(app)
            disp('Resetting model to approximate GT values');
            
            app.model_hold = [];
            app.HoldButton.Value = false;
            
            analysis = TireAnalysis();
            app.model = analysis.resetModelToApproximateGT(app.model);
            app.model_prev = app.model;
            
            app.state.alpha = 0;
            app.state.kappa = 0;
            app.state.Fz = app.model.Fz0;
            app.state.P = app.model.P0;
            app.state.gamma = deg2rad(2.5);
            app.state.Vs = 0;
            app.state.V0 = 30.0;
            
            app.state_prev = app.state;
            
            app.updateSliders();
            app.replot();
        end
    
        function updateSliders(app)
            % Vehicle states
            app.SlipAngledegSlider.Value = rad2deg(app.state.alpha);
            app.SlipRatioSlider.Value = app.state.kappa;
            app.CamberdegSlider.Value = rad2deg(app.state.gamma);
            app.Fz0NSlider.Value = app.model.Fz0;
            app.FzNSlider.Value = app.state.Fz;
            app.P0psiSlider.Value = app.model.P0;
            app.PpsiSlider.Value = app.state.P;
            app.V0kmhSlider.Value = 3.6 * app.state.V0;
            app.VskmhSlider.Value = 3.6 * app.state.Vs;
            
            % Fx0
            app.PCx1Slider.Value = app.model.PFx0(app.model.ID_PCx1);
            app.PDx1Slider.Value = app.model.PFx0(app.model.ID_PDx1);
            app.PDx2Slider.Value = app.model.PFx0(app.model.ID_PDx2);
            app.PDx3Slider.Value = app.model.PFx0(app.model.ID_PDx3);
            app.PEx1Slider.Value = app.model.PFx0(app.model.ID_PEx1);
            app.PEx2Slider.Value = app.model.PFx0(app.model.ID_PEx2);
            app.PEx3Slider.Value = app.model.PFx0(app.model.ID_PEx3);
            app.PEx4Slider.Value = app.model.PFx0(app.model.ID_PEx4);
            app.PKx1Slider.Value = app.model.PFx0(app.model.ID_PKx1);
            app.PKx2Slider.Value = app.model.PFx0(app.model.ID_PKx2);
            app.PKx3Slider.Value = app.model.PFx0(app.model.ID_PKx3);
            app.PHx1Slider.Value = app.model.PFx0(app.model.ID_PHx1);
            app.PHx2Slider.Value = app.model.PFx0(app.model.ID_PHx2);
            app.PVx1Slider.Value = app.model.PFx0(app.model.ID_PVx1);
            app.PVx2Slider.Value = app.model.PFx0(app.model.ID_PVx2);
            app.Ppx1Slider.Value = app.model.PFx0(app.model.ID_Ppx1);
            app.Ppx2Slider.Value = app.model.PFx0(app.model.ID_Ppx2);
            app.Ppx3Slider.Value = app.model.PFx0(app.model.ID_Ppx3);
            app.Ppx4Slider.Value = app.model.PFx0(app.model.ID_Ppx4);
            
            % Fx
            app.RBx1Slider.Value = app.model.PFx(app.model.ID_RBx1);
            app.RBx2Slider.Value = app.model.PFx(app.model.ID_RBx2);
            app.RBx3Slider.Value = app.model.PFx(app.model.ID_RBx3);
            app.RCx1Slider.Value = app.model.PFx(app.model.ID_RCx1);
            app.REx1Slider.Value = app.model.PFx(app.model.ID_REx1);
            app.REx2Slider.Value = app.model.PFx(app.model.ID_REx2);
            app.RHx1Slider.Value = app.model.PFx(app.model.ID_RHx1);
            
            % Fy0
            app.PCy1Slider.Value = app.model.PFy0(app.model.ID_PCy1);
            app.PDy1Slider.Value = app.model.PFy0(app.model.ID_PDy1);
            app.PDy2Slider.Value = app.model.PFy0(app.model.ID_PDy2);
            app.PDy3Slider.Value = app.model.PFy0(app.model.ID_PDy3);
            app.PEy1Slider.Value = app.model.PFy0(app.model.ID_PEy1);
            app.PEy2Slider.Value = app.model.PFy0(app.model.ID_PEy2);
            app.PEy3Slider.Value = app.model.PFy0(app.model.ID_PEy3);
            app.PEy4Slider.Value = app.model.PFy0(app.model.ID_PEy4);
            app.PEy5Slider.Value = app.model.PFy0(app.model.ID_PEy5);
            app.PKy1Slider.Value = app.model.PFy0(app.model.ID_PKy1);
            app.PKy2Slider.Value = app.model.PFy0(app.model.ID_PKy2);
            app.PKy3Slider.Value = app.model.PFy0(app.model.ID_PKy3);
            app.PKy4Slider.Value = app.model.PFy0(app.model.ID_PKy4);
            app.PKy5Slider.Value = app.model.PFy0(app.model.ID_PKy5);
            app.PKy6Slider.Value = app.model.PFy0(app.model.ID_PKy6);
            app.PKy7Slider.Value = app.model.PFy0(app.model.ID_PKy7);
            app.PHy1Slider.Value = app.model.PFy0(app.model.ID_PHy1);
            app.PHy2Slider.Value = app.model.PFy0(app.model.ID_PHy2);
            app.PVy1Slider.Value = app.model.PFy0(app.model.ID_PVy1);
            app.PVy2Slider.Value = app.model.PFy0(app.model.ID_PVy2);
            app.PVy3Slider.Value = app.model.PFy0(app.model.ID_PVy3);
            app.PVy4Slider.Value = app.model.PFy0(app.model.ID_PVy4);
            app.Ppy1Slider.Value = app.model.PFy0(app.model.ID_Ppy1);
            app.Ppy2Slider.Value = app.model.PFy0(app.model.ID_Ppy2);
            app.Ppy3Slider.Value = app.model.PFy0(app.model.ID_Ppy3);
            app.Ppy4Slider.Value = app.model.PFy0(app.model.ID_Ppy4);
            app.Ppy5Slider.Value = app.model.PFy0(app.model.ID_Ppy5);
            
            % Fy
            app.RBy1Slider.Value = app.model.PFy(app.model.ID_RBy1);
            app.RBy2Slider.Value = app.model.PFy(app.model.ID_RBy2);
            app.RBy3Slider.Value = app.model.PFy(app.model.ID_RBy3);
            app.RBy4Slider.Value = app.model.PFy(app.model.ID_RBy4);
            app.RCy1Slider.Value = app.model.PFy(app.model.ID_RCy1);
            app.REy1Slider.Value = app.model.PFy(app.model.ID_REy1);
            app.REy2Slider.Value = app.model.PFy(app.model.ID_REy2);
            app.RHy1Slider.Value = app.model.PFy(app.model.ID_RHy1);
            app.RHy2Slider.Value = app.model.PFy(app.model.ID_RHy2);
            app.RVy1Slider.Value = app.model.PFy(app.model.ID_RVy1);
            app.RVy2Slider.Value = app.model.PFy(app.model.ID_RVy2);
            app.RVy3Slider.Value = app.model.PFy(app.model.ID_RVy3);
            app.RVy4Slider.Value = app.model.PFy(app.model.ID_RVy4);
            app.RVy5Slider.Value = app.model.PFy(app.model.ID_RVy5);
            app.RVy6Slider.Value = app.model.PFy(app.model.ID_RVy6);
        end
    
        function updateParameters(app)
            % Vehicle states
            app.state.alpha = deg2rad(app.SlipAngledegSlider.Value);
            app.state.kappa = app.SlipRatioSlider.Value;
            app.state.gamma = deg2rad(app.CamberdegSlider.Value);
            app.model.Fz0 = app.Fz0NSlider.Value;
            app.state.Fz = app.FzNSlider.Value;
            app.model.P0 = app.P0psiSlider.Value;
            app.state.P = app.PpsiSlider.Value;
            app.state.V0 = app.V0kmhSlider.Value / 3.6;
            app.state.Vs = app.VskmhSlider.Value / 3.6;
            
            % Fx0
            app.model.PFx0(app.model.ID_PCx1) = app.PCx1Slider.Value;
            app.model.PFx0(app.model.ID_PDx1) = app.PDx1Slider.Value;
            app.model.PFx0(app.model.ID_PDx2) = app.PDx2Slider.Value;
            app.model.PFx0(app.model.ID_PDx3) = app.PDx3Slider.Value;
            app.model.PFx0(app.model.ID_PEx1) = app.PEx1Slider.Value;
            app.model.PFx0(app.model.ID_PEx2) = app.PEx2Slider.Value;
            app.model.PFx0(app.model.ID_PEx3) = app.PEx3Slider.Value;
            app.model.PFx0(app.model.ID_PEx4) = app.PEx4Slider.Value;
            app.model.PFx0(app.model.ID_PKx1) = app.PKx1Slider.Value;
            app.model.PFx0(app.model.ID_PKx2) = app.PKx2Slider.Value;
            app.model.PFx0(app.model.ID_PKx3) = app.PKx3Slider.Value;
            app.model.PFx0(app.model.ID_PHx1) = app.PHx1Slider.Value;
            app.model.PFx0(app.model.ID_PHx2) = app.PHx2Slider.Value;
            app.model.PFx0(app.model.ID_PVx1) = app.PVx1Slider.Value;
            app.model.PFx0(app.model.ID_PVx2) = app.PVx2Slider.Value;
            app.model.PFx0(app.model.ID_Ppx1) = app.Ppx1Slider.Value;
            app.model.PFx0(app.model.ID_Ppx2) = app.Ppx2Slider.Value;
            app.model.PFx0(app.model.ID_Ppx3) = app.Ppx3Slider.Value;
            app.model.PFx0(app.model.ID_Ppx4) = app.Ppx4Slider.Value;
            
            % Fx
            app.model.PFx(app.model.ID_RBx1) = app.RBx1Slider.Value;
            app.model.PFx(app.model.ID_RBx2) = app.RBx2Slider.Value;
            app.model.PFx(app.model.ID_RBx3) = app.RBx3Slider.Value;
            app.model.PFx(app.model.ID_RCx1) = app.RCx1Slider.Value;
            app.model.PFx(app.model.ID_REx1) = app.REx1Slider.Value;
            app.model.PFx(app.model.ID_REx2) = app.REx2Slider.Value;
            app.model.PFx(app.model.ID_RHx1) = app.RHx1Slider.Value;
            
            % Fy0
            app.model.PFy0(app.model.ID_PCy1) = app.PCy1Slider.Value;
            app.model.PFy0(app.model.ID_PDy1) = app.PDy1Slider.Value;
            app.model.PFy0(app.model.ID_PDy2) = app.PDy2Slider.Value;
            app.model.PFy0(app.model.ID_PDy3) = app.PDy3Slider.Value;
            app.model.PFy0(app.model.ID_PEy1) = app.PEy1Slider.Value;
            app.model.PFy0(app.model.ID_PEy2) = app.PEy2Slider.Value;
            app.model.PFy0(app.model.ID_PEy3) = app.PEy3Slider.Value;
            app.model.PFy0(app.model.ID_PEy4) = app.PEy4Slider.Value;
            app.model.PFy0(app.model.ID_PEy5) = app.PEy5Slider.Value;
            app.model.PFy0(app.model.ID_PKy1) = app.PKy1Slider.Value;
            app.model.PFy0(app.model.ID_PKy2) = app.PKy2Slider.Value;
            app.model.PFy0(app.model.ID_PKy3) = app.PKy3Slider.Value;
            app.model.PFy0(app.model.ID_PKy4) = app.PKy4Slider.Value;
            app.model.PFy0(app.model.ID_PKy5) = app.PKy5Slider.Value;
            app.model.PFy0(app.model.ID_PKy6) = app.PKy6Slider.Value;
            app.model.PFy0(app.model.ID_PKy7) = app.PKy7Slider.Value;
            app.model.PFy0(app.model.ID_PHy1) = app.PHy1Slider.Value;
            app.model.PFy0(app.model.ID_PHy2) = app.PHy2Slider.Value;
            app.model.PFy0(app.model.ID_PVy1) = app.PVy1Slider.Value;
            app.model.PFy0(app.model.ID_PVy2) = app.PVy2Slider.Value;
            app.model.PFy0(app.model.ID_PVy3) = app.PVy3Slider.Value;
            app.model.PFy0(app.model.ID_PVy4) = app.PVy4Slider.Value;
            app.model.PFy0(app.model.ID_Ppy1) = app.Ppy1Slider.Value;
            app.model.PFy0(app.model.ID_Ppy2) = app.Ppy2Slider.Value;
            app.model.PFy0(app.model.ID_Ppy3) = app.Ppy3Slider.Value;
            app.model.PFy0(app.model.ID_Ppy4) = app.Ppy4Slider.Value;
            app.model.PFy0(app.model.ID_Ppy5) = app.Ppy5Slider.Value;
            
            % Fy
            app.model.PFy(app.model.ID_RBy1) = app.RBy1Slider.Value;
            app.model.PFy(app.model.ID_RBy2) = app.RBy2Slider.Value;
            app.model.PFy(app.model.ID_RBy3) = app.RBy3Slider.Value;
            app.model.PFy(app.model.ID_RBy4) = app.RBy4Slider.Value;
            app.model.PFy(app.model.ID_RCy1) = app.RCy1Slider.Value;
            app.model.PFy(app.model.ID_REy1) = app.REy1Slider.Value;
            app.model.PFy(app.model.ID_REy2) = app.REy2Slider.Value;
            app.model.PFy(app.model.ID_RHy1) = app.RHy1Slider.Value;
            app.model.PFy(app.model.ID_RHy2) = app.RHy2Slider.Value;
            app.model.PFy(app.model.ID_RVy1) = app.RVy1Slider.Value;
            app.model.PFy(app.model.ID_RVy2) = app.RVy2Slider.Value;
            app.model.PFy(app.model.ID_RVy3) = app.RVy3Slider.Value;
            app.model.PFy(app.model.ID_RVy4) = app.RVy4Slider.Value;
            app.model.PFy(app.model.ID_RVy5) = app.RVy5Slider.Value;
            app.model.PFy(app.model.ID_RVy6) = app.RVy6Slider.Value;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            app.state.alpha = 0;
            app.state.kappa = 0;
            app.state.Fz = 0;
            app.state.P = 0;
            app.state.gamma = 0;
            app.state.Vs = 0;
            app.state.V0 = 30;
            
            app.reset();
            app.save();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.save();
            app.reset();
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            if app.HoldButton.Value
                app.model_hold = app.model;
                app.state_hold = app.state;
            else
                app.model_hold = [];
                app.replot();
            end            
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.model = app.model_prev;
            app.state = app.state_prev;
            app.updateSliders();
            app.replot();
        end

        % Value changed function: CamberdegSlider, Fz0NSlider, 
        % FzNSlider, PCx1Slider, PCy1Slider, PDx1Slider, PDx2Slider, 
        % PDx3Slider, PDy1Slider, PDy2Slider, PDy3Slider, PEx1Slider, 
        % PEx2Slider, PEx3Slider, PEx4Slider, PEy1Slider, PEy2Slider, 
        % PEy3Slider, PEy4Slider, PEy5Slider, PHx1Slider, PHx2Slider, 
        % PHy1Slider, PHy2Slider, PKx1Slider, PKx2Slider, PKx3Slider, 
        % PKy1Slider, PKy2Slider, PKy3Slider, PKy4Slider, PKy5Slider, 
        % PKy6Slider, PKy7Slider, PVx1Slider, PVx2Slider, PVy1Slider, 
        % PVy2Slider, PVy3Slider, PVy4Slider, Ppx1Slider, Ppx2Slider, 
        % Ppx3Slider, Ppx4Slider, Ppy1Slider, Ppy2Slider, Ppy3Slider, 
        % Ppy4Slider, Ppy5Slider, RBx1Slider, RBx2Slider, RBx3Slider, 
        % RBy1Slider, RBy2Slider, RBy3Slider, RBy4Slider, RCx1Slider, 
        % RCy1Slider, REx1Slider, REx2Slider, REy1Slider, REy2Slider, 
        % RHx1Slider, RHy1Slider, RHy2Slider, RVy1Slider, RVy2Slider, 
        % RVy3Slider, RVy4Slider, RVy5Slider, RVy6Slider, 
        % SlipAngledegSlider, SlipRatioSlider
        function sliderCallback(app, event)
            app.save();
            app.updateParameters();
            app.replot();
        end

        % Button pushed function: PrintParamatersButton
        function PrintParamatersButtonPushed(app, event)
            app.model.printParameters();
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            app.model.saveModel();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PacejkaTireModelUIFigure = uifigure;
            app.PacejkaTireModelUIFigure.Name = 'Pacejka Tire Model';
            app.FxPlot = uiaxes(app.PacejkaTireModelUIFigure);
            title(app.FxPlot, 'Longitudinal Force')
            xlabel(app.FxPlot, 'Slip Ratio')
            ylabel(app.FxPlot, 'Fx / Fz')
            app.SlipAngledegSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.SlipAngledegSliderLabel.Text = 'Slip Angle [deg]';
            app.SlipAngledegSlider = uislider(app.PacejkaTireModelUIFigure);
            app.SlipAngledegSlider.Limits = [-45 45];
            app.SlipAngledegSlider.MajorTicks = [-45 -35 -25 -15 -5 5 15 25 35 45];
            app.SlipAngledegSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.ResetButton = uibutton(app.PacejkaTireModelUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SlipRatioSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.SlipRatioSliderLabel.Text = 'Slip Ratio';
            app.SlipRatioSlider = uislider(app.PacejkaTireModelUIFigure);
            app.SlipRatioSlider.Limits = [0 1];
            app.SlipRatioSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.UndoButton = uibutton(app.PacejkaTireModelUIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.HoldButton = uibutton(app.PacejkaTireModelUIFigure, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Text = 'Hold';
            app.Fz0NSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.Fz0NSliderLabel.Text = 'Fz0 [N]';
            app.Fz0NSlider = uislider(app.PacejkaTireModelUIFigure);
            app.Fz0NSlider.Limits = [0 6000];
            app.Fz0NSlider.MajorTicks = [0 1000 2000 3000 4000 5000 6000];
            app.Fz0NSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.CamberdegSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.CamberdegSliderLabel.Text = 'Camber [deg]';
            app.CamberdegSlider = uislider(app.PacejkaTireModelUIFigure);
            app.CamberdegSlider.Limits = [0 5];
            app.CamberdegSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.FzNSlider_2Label = uilabel(app.PacejkaTireModelUIFigure);
            app.FzNSlider_2Label.Text = 'Fz [N]';
            app.FzNSlider = uislider(app.PacejkaTireModelUIFigure);
            app.FzNSlider.Limits = [0 6000];
            app.FzNSlider.MajorTicks = [0 1000 2000 3000 4000 5000 6000];
            app.FzNSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.TabGroup = uitabgroup(app.PacejkaTireModelUIFigure);
            app.Fx0Tab = uitab(app.TabGroup);
            app.Fx0Tab.Title = 'Fx0';
            app.PCx1SliderLabel = uilabel(app.Fx0Tab);
            app.PCx1SliderLabel.Text = 'PCx1';
            app.PCx1Slider = uislider(app.Fx0Tab);
            app.PCx1Slider.Limits = [0 2];
            app.PCx1Slider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.PCx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PCx1Slider.Value = 1;
            app.PDx1SliderLabel = uilabel(app.Fx0Tab);
            app.PDx1SliderLabel.Text = 'PDx1';
            app.PDx1Slider = uislider(app.Fx0Tab);
            app.PDx1Slider.Limits = [0.5 3];
            app.PDx1Slider.MajorTicks = [0.5 1 1.5 2 2.5 3];
            app.PDx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PDx1Slider.Value = 1;
            app.PDx2SliderLabel = uilabel(app.Fx0Tab);
            app.PDx2SliderLabel.Text = 'PDx2';
            app.PDx2Slider = uislider(app.Fx0Tab);
            app.PDx2Slider.Limits = [-0.5 0.5];
            app.PDx2Slider.MajorTicks = [-0.5 -0.25 0 0.25 0.5];
            app.PDx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PDx3SliderLabel = uilabel(app.Fx0Tab);
            app.PDx3SliderLabel.Text = 'PDx3';
            app.PDx3Slider = uislider(app.Fx0Tab);
            app.PDx3Slider.Limits = [-20 20];
            app.PDx3Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.PDx3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEx1SliderLabel = uilabel(app.Fx0Tab);
            app.PEx1SliderLabel.Text = 'PEx1';
            app.PEx1Slider = uislider(app.Fx0Tab);
            app.PEx1Slider.Limits = [-10 1];
            app.PEx1Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.PEx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEx1Slider.Value = -1;
            app.PEx2SliderLabel = uilabel(app.Fx0Tab);
            app.PEx2SliderLabel.Text = 'PEx2';
            app.PEx2Slider = uislider(app.Fx0Tab);
            app.PEx2Slider.Limits = [-5 5];
            app.PEx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEx3SliderLabel = uilabel(app.Fx0Tab);
            app.PEx3SliderLabel.Text = 'PEx3';
            app.PEx3Slider = uislider(app.Fx0Tab);
            app.PEx3Slider.Limits = [-5 5];
            app.PEx3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKx1SliderLabel = uilabel(app.Fx0Tab);
            app.PKx1SliderLabel.Text = 'PKx1';
            app.PKx1Slider = uislider(app.Fx0Tab);
            app.PKx1Slider.Limits = [0 150];
            app.PKx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKx1Slider.Value = 70;
            app.Ppx1SliderLabel = uilabel(app.Fx0Tab);
            app.Ppx1SliderLabel.Text = 'Ppx1';
            app.Ppx1Slider = uislider(app.Fx0Tab);
            app.Ppx1Slider.Limits = [-3 3];
            app.Ppx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEx4SliderLabel = uilabel(app.Fx0Tab);
            app.PEx4SliderLabel.Text = 'PEx4';
            app.PEx4Slider = uislider(app.Fx0Tab);
            app.PEx4Slider.Limits = [-1 1];
            app.PEx4Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.PEx4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKx2SliderLabel = uilabel(app.Fx0Tab);
            app.PKx2SliderLabel.Text = 'PKx2';
            app.PKx2Slider = uislider(app.Fx0Tab);
            app.PKx2Slider.Limits = [-50 0];
            app.PKx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKx3SliderLabel = uilabel(app.Fx0Tab);
            app.PKx3SliderLabel.Text = 'PKx3';
            app.PKx3Slider = uislider(app.Fx0Tab);
            app.PKx3Slider.Limits = [-2 0];
            app.PKx3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PHx1Slider_5Label = uilabel(app.Fx0Tab);
            app.PHx1Slider_5Label.Text = 'PHx1';
            app.PHx1Slider = uislider(app.Fx0Tab);
            app.PHx1Slider.Limits = [-0.1 0.1];
            app.PHx1Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.PHx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PHx2SliderLabel = uilabel(app.Fx0Tab);
            app.PHx2SliderLabel.Text = 'PHx2';
            app.PHx2Slider = uislider(app.Fx0Tab);
            app.PHx2Slider.Limits = [-0.1 0.1];
            app.PHx2Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.PHx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PVx1SliderLabel = uilabel(app.Fx0Tab);
            app.PVx1SliderLabel.Text = 'PVx1';
            app.PVx1Slider = uislider(app.Fx0Tab);
            app.PVx1Slider.Limits = [-1 1];
            app.PVx1Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.PVx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PVx2SliderLabel = uilabel(app.Fx0Tab);
            app.PVx2SliderLabel.Text = 'PVx2';
            app.PVx2Slider = uislider(app.Fx0Tab);
            app.PVx2Slider.Limits = [-1 1];
            app.PVx2Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.PVx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppx2SliderLabel = uilabel(app.Fx0Tab);
            app.Ppx2SliderLabel.Text = 'Ppx2';
            app.Ppx2Slider = uislider(app.Fx0Tab);
            app.Ppx2Slider.Limits = [-20 20];
            app.Ppx2Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Ppx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppx3SliderLabel = uilabel(app.Fx0Tab);
            app.Ppx3SliderLabel.Text = 'Ppx3';
            app.Ppx3Slider = uislider(app.Fx0Tab);
            app.Ppx3Slider.Limits = [-1 1];
            app.Ppx3Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.Ppx3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppx4SliderLabel = uilabel(app.Fx0Tab);
            app.Ppx4SliderLabel.Text = 'Ppx4';
            app.Ppx4Slider = uislider(app.Fx0Tab);
            app.Ppx4Slider.Limits = [-3 0];
            app.Ppx4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.FxTab = uitab(app.TabGroup);
            app.FxTab.Title = 'Fx';
            app.RBx1SliderLabel = uilabel(app.FxTab);
            app.RBx1SliderLabel.Text = 'RBx1';
            app.RBx1Slider = uislider(app.FxTab);
            app.RBx1Slider.Limits = [0 10];
            app.RBx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RBx1Slider.Value = 1;
            app.RBx2SliderLabel = uilabel(app.FxTab);
            app.RBx2SliderLabel.Text = 'RBx2';
            app.RBx2Slider = uislider(app.FxTab);
            app.RBx2Slider.Limits = [0 10];
            app.RBx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RBx3SliderLabel = uilabel(app.FxTab);
            app.RBx3SliderLabel.Text = 'RBx3';
            app.RBx3Slider = uislider(app.FxTab);
            app.RBx3Slider.Limits = [-200 200];
            app.RBx3Slider.MajorTicks = [-200 -150 -100 -50 0 50 100 150 200];
            app.RBx3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RCx1SliderLabel = uilabel(app.FxTab);
            app.RCx1SliderLabel.Text = 'RCx1';
            app.RCx1Slider = uislider(app.FxTab);
            app.RCx1Slider.Limits = [0 1.5];
            app.RCx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RCx1Slider.Value = 1;
            app.REx1SliderLabel = uilabel(app.FxTab);
            app.REx1SliderLabel.Text = 'REx1';
            app.REx1Slider = uislider(app.FxTab);
            app.REx1Slider.Limits = [-10 1];
            app.REx1Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.REx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.REx2SliderLabel = uilabel(app.FxTab);
            app.REx2SliderLabel.Text = 'REx2';
            app.REx2Slider = uislider(app.FxTab);
            app.REx2Slider.Limits = [-10 1];
            app.REx2Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.REx2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RHx1SliderLabel = uilabel(app.FxTab);
            app.RHx1SliderLabel.Text = 'RHx1';
            app.RHx1Slider = uislider(app.FxTab);
            app.RHx1Slider.Limits = [-0.1 0.1];
            app.RHx1Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.RHx1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Fy0Tab = uitab(app.TabGroup);
            app.Fy0Tab.Title = 'Fy0';
            app.PCy1SliderLabel = uilabel(app.Fy0Tab);
            app.PCy1SliderLabel.Text = 'PCy1';
            app.PCy1Slider = uislider(app.Fy0Tab);
            app.PCy1Slider.Limits = [0 2];
            app.PCy1Slider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.PCy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PCy1Slider.Value = 1;
            app.PDy1SliderLabel = uilabel(app.Fy0Tab);
            app.PDy1SliderLabel.Text = 'PDy1';
            app.PDy1Slider = uislider(app.Fy0Tab);
            app.PDy1Slider.Limits = [0.5 3];
            app.PDy1Slider.MajorTicks = [0.5 1 1.5 2 2.5 3];
            app.PDy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PDy1Slider.Value = 1;
            app.PDy2SliderLabel = uilabel(app.Fy0Tab);
            app.PDy2SliderLabel.Text = 'PDy2';
            app.PDy2Slider = uislider(app.Fy0Tab);
            app.PDy2Slider.Limits = [-0.5 0.5];
            app.PDy2Slider.MajorTicks = [-0.5 -0.25 0 0.25 0.5];
            app.PDy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PDy3SliderLabel = uilabel(app.Fy0Tab);
            app.PDy3SliderLabel.Text = 'PDy3';
            app.PDy3Slider = uislider(app.Fy0Tab);
            app.PDy3Slider.Limits = [-20 20];
            app.PDy3Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.PDy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEy1SliderLabel = uilabel(app.Fy0Tab);
            app.PEy1SliderLabel.Text = 'PEy1';
            app.PEy1Slider = uislider(app.Fy0Tab);
            app.PEy1Slider.Limits = [-10 1];
            app.PEy1Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.PEy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEy1Slider.Value = -1;
            app.PEy2SliderLabel = uilabel(app.Fy0Tab);
            app.PEy2SliderLabel.Text = 'PEy2';
            app.PEy2Slider = uislider(app.Fy0Tab);
            app.PEy2Slider.Limits = [-5 5];
            app.PEy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEy3SliderLabel = uilabel(app.Fy0Tab);
            app.PEy3SliderLabel.Text = 'PEy3';
            app.PEy3Slider = uislider(app.Fy0Tab);
            app.PEy3Slider.Limits = [-1 1];
            app.PEy3Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.PEy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEy4SliderLabel = uilabel(app.Fy0Tab);
            app.PEy4SliderLabel.Text = 'PEy4';
            app.PEy4Slider = uislider(app.Fy0Tab);
            app.PEy4Slider.Limits = [-10 10];
            app.PEy4Slider.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.PEy4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PEy5SliderLabel = uilabel(app.Fy0Tab);
            app.PEy5SliderLabel.Text = 'PEy5';
            app.PEy5Slider = uislider(app.Fy0Tab);
            app.PEy5Slider.Limits = [-100 1000];
            app.PEy5Slider.MajorTicks = [-100 100 300 500 700 1000];
            app.PEy5Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy1SliderLabel = uilabel(app.Fy0Tab);
            app.PKy1SliderLabel.Text = 'PKy1';
            app.PKy1Slider = uislider(app.Fy0Tab);
            app.PKy1Slider.Limits = [0 200];
            app.PKy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy1Slider.Value = 80;
            app.PKy2SliderLabel = uilabel(app.Fy0Tab);
            app.PKy2SliderLabel.Text = 'PKy2';
            app.PKy2Slider = uislider(app.Fy0Tab);
            app.PKy2Slider.Limits = [0 5];
            app.PKy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy2Slider.Value = 1;
            app.PKy3SliderLabel = uilabel(app.Fy0Tab);
            app.PKy3SliderLabel.Text = 'PKy3';
            app.PKy3Slider = uislider(app.Fy0Tab);
            app.PKy3Slider.Limits = [-5 5];
            app.PKy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy4SliderLabel = uilabel(app.Fy0Tab);
            app.PKy4SliderLabel.Text = 'PKy4';
            app.PKy4Slider = uislider(app.Fy0Tab);
            app.PKy4Slider.Limits = [0 2];
            app.PKy4Slider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.PKy4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy4Slider.Value = 1;
            app.PKy5SliderLabel = uilabel(app.Fy0Tab);
            app.PKy5SliderLabel.Text = 'PKy5';
            app.PKy5Slider = uislider(app.Fy0Tab);
            app.PKy5Slider.Limits = [-100 100];
            app.PKy5Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy6SliderLabel = uilabel(app.Fy0Tab);
            app.PKy6SliderLabel.Text = 'PKy6';
            app.PKy6Slider = uislider(app.Fy0Tab);
            app.PKy6Slider.Limits = [0 10];
            app.PKy6Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PKy6Slider.Value = 5;
            app.PKy7SliderLabel = uilabel(app.Fy0Tab);
            app.PKy7SliderLabel.Text = 'PKy7';
            app.PKy7Slider = uislider(app.Fy0Tab);
            app.PKy7Slider.Limits = [0 10];
            app.PKy7Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PHy1SliderLabel = uilabel(app.Fy0Tab);
            app.PHy1SliderLabel.Text = 'PHy1';
            app.PHy1Slider = uislider(app.Fy0Tab);
            app.PHy1Slider.Limits = [-0.1 0.1];
            app.PHy1Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.PHy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PHy2SliderLabel = uilabel(app.Fy0Tab);
            app.PHy2SliderLabel.Text = 'PHy2';
            app.PHy2Slider = uislider(app.Fy0Tab);
            app.PHy2Slider.Limits = [-0.05 0.05];
            app.PHy2Slider.MajorTicks = [-0.05 -0.025 0 0.025 0.05];
            app.PHy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PVy1SliderLabel = uilabel(app.Fy0Tab);
            app.PVy1SliderLabel.Text = 'PVy1';
            app.PVy1Slider = uislider(app.Fy0Tab);
            app.PVy1Slider.Limits = [-0.2 0.2];
            app.PVy1Slider.MajorTicks = [-0.2 -0.1 0 0.1 0.2];
            app.PVy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PVy2SliderLabel = uilabel(app.Fy0Tab);
            app.PVy2SliderLabel.Text = 'PVy2';
            app.PVy2Slider = uislider(app.Fy0Tab);
            app.PVy2Slider.Limits = [-0.2 0.2];
            app.PVy2Slider.MajorTicks = [-0.2 -0.1 0 0.1 0.2];
            app.PVy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PVy3SliderLabel = uilabel(app.Fy0Tab);
            app.PVy3SliderLabel.Text = 'PVy3';
            app.PVy3Slider = uislider(app.Fy0Tab);
            app.PVy3Slider.Limits = [-2 2];
            app.PVy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.PVy4SliderLabel = uilabel(app.Fy0Tab);
            app.PVy4SliderLabel.Text = 'PVy4';
            app.PVy4Slider = uislider(app.Fy0Tab);
            app.PVy4Slider.Limits = [-2 2];
            app.PVy4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppy1SliderLabel = uilabel(app.Fy0Tab);
            app.Ppy1SliderLabel.Text = 'Ppy1';
            app.Ppy1Slider = uislider(app.Fy0Tab);
            app.Ppy1Slider.Limits = [0 3];
            app.Ppy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppy2SliderLabel = uilabel(app.Fy0Tab);
            app.Ppy2SliderLabel.Text = 'Ppy2';
            app.Ppy2Slider = uislider(app.Fy0Tab);
            app.Ppy2Slider.Limits = [-3 0];
            app.Ppy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppy3SliderLabel = uilabel(app.Fy0Tab);
            app.Ppy3SliderLabel.Text = 'Ppy3';
            app.Ppy3Slider = uislider(app.Fy0Tab);
            app.Ppy3Slider.Limits = [-1 1];
            app.Ppy3Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.Ppy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppy4SliderLabel = uilabel(app.Fy0Tab);
            app.Ppy4SliderLabel.Text = 'Ppy4';
            app.Ppy4Slider = uislider(app.Fy0Tab);
            app.Ppy4Slider.Limits = [-3 0];
            app.Ppy4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.Ppy5SliderLabel = uilabel(app.Fy0Tab);
            app.Ppy5SliderLabel.Text = 'Ppy5';
            app.Ppy5Slider = uislider(app.Fy0Tab);
            app.Ppy5Slider.Limits = [-2 2];
            app.Ppy5Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.FyTab = uitab(app.TabGroup);
            app.FyTab.Title = 'Fy';
            app.RBy1SliderLabel = uilabel(app.FyTab);
            app.RBy1SliderLabel.Text = 'RBy1';
            app.RBy1Slider = uislider(app.FyTab);
            app.RBy1Slider.Limits = [0 10];
            app.RBy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RBy1Slider.Value = 1;
            app.RBy2SliderLabel = uilabel(app.FyTab);
            app.RBy2SliderLabel.Text = 'RBy2';
            app.RBy2Slider = uislider(app.FyTab);
            app.RBy2Slider.Limits = [0 10];
            app.RBy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RBy3SliderLabel = uilabel(app.FyTab);
            app.RBy3SliderLabel.Text = 'RBy3';
            app.RBy3Slider = uislider(app.FyTab);
            app.RBy3Slider.Limits = [0 0.5];
            app.RBy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RBy4SliderLabel = uilabel(app.FyTab);
            app.RBy4SliderLabel.Text = 'RBy4';
            app.RBy4Slider = uislider(app.FyTab);
            app.RBy4Slider.Limits = [-200 200];
            app.RBy4Slider.MajorTicks = [-200 -150 -100 -50 0 50 100 150 200];
            app.RBy4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RCy1SliderLabel = uilabel(app.FyTab);
            app.RCy1SliderLabel.Text = 'RCy1';
            app.RCy1Slider = uislider(app.FyTab);
            app.RCy1Slider.Limits = [0 1.5];
            app.RCy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RCy1Slider.Value = 1;
            app.REy1SliderLabel = uilabel(app.FyTab);
            app.REy1SliderLabel.Text = 'REy1';
            app.REy1Slider = uislider(app.FyTab);
            app.REy1Slider.Limits = [-10 1];
            app.REy1Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.REy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.REy2SliderLabel = uilabel(app.FyTab);
            app.REy2SliderLabel.Text = 'REy2';
            app.REy2Slider = uislider(app.FyTab);
            app.REy2Slider.Limits = [-10 1];
            app.REy2Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.REy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RHy1SliderLabel = uilabel(app.FyTab);
            app.RHy1SliderLabel.Text = 'RHy1';
            app.RHy1Slider = uislider(app.FyTab);
            app.RHy1Slider.Limits = [-0.1 0.1];
            app.RHy1Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.RHy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RHy2SliderLabel = uilabel(app.FyTab);
            app.RHy2SliderLabel.Text = 'RHy2';
            app.RHy2Slider = uislider(app.FyTab);
            app.RHy2Slider.Limits = [-0.1 0.1];
            app.RHy2Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.RHy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy1SliderLabel = uilabel(app.FyTab);
            app.RVy1SliderLabel.Text = 'RVy1';
            app.RVy1Slider = uislider(app.FyTab);
            app.RVy1Slider.Limits = [-0.1 0.1];
            app.RVy1Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.RVy1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy2SliderLabel = uilabel(app.FyTab);
            app.RVy2SliderLabel.Text = 'RVy2';
            app.RVy2Slider = uislider(app.FyTab);
            app.RVy2Slider.Limits = [-0.1 0.1];
            app.RVy2Slider.MajorTicks = [-0.1 -0.05 0 0.05 0.1];
            app.RVy2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy3SliderLabel = uilabel(app.FyTab);
            app.RVy3SliderLabel.Text = 'RVy3';
            app.RVy3Slider = uislider(app.FyTab);
            app.RVy3Slider.Limits = [-1 0];
            app.RVy3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy4SliderLabel = uilabel(app.FyTab);
            app.RVy4SliderLabel.Text = 'RVy4';
            app.RVy4Slider = uislider(app.FyTab);
            app.RVy4Slider.Limits = [0 20];
            app.RVy4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy4Slider.Value = 10;
            app.RVy5SliderLabel = uilabel(app.FyTab);
            app.RVy5SliderLabel.Text = 'RVy5';
            app.RVy5Slider = uislider(app.FyTab);
            app.RVy5Slider.Limits = [0 2];
            app.RVy5Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.RVy5Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy5Slider.Value = 1;
            app.RVy6SliderLabel = uilabel(app.FyTab);
            app.RVy6SliderLabel.Text = 'RVy6';
            app.RVy6Slider = uislider(app.FyTab);
            app.RVy6Slider.Limits = [0 20];
            app.RVy6Slider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.RVy6Slider.Value = 10;
            app.FyPlot = uiaxes(app.PacejkaTireModelUIFigure);
            title(app.FyPlot, 'Lateral Force')
            xlabel(app.FyPlot, 'Slip Angle')
            ylabel(app.FyPlot, 'Fy / Fz')
            app.PpsiSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.PpsiSliderLabel.Text = 'P [psi]';
            app.PpsiSlider = uislider(app.PacejkaTireModelUIFigure);
            app.PpsiSlider.Limits = [20 35];
            app.PpsiSlider.MajorTicks = [20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35];
            app.PpsiSlider.Value = 27.5;
            app.V0kmhSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.V0kmhSliderLabel.Text = 'V0 [km/h]';
            app.V0kmhSlider = uislider(app.PacejkaTireModelUIFigure);
            app.V0kmhSlider.Limits = [0 300];
            app.V0kmhSlider.Value = 150;
            app.VskmhSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.VskmhSliderLabel.Text = 'Vs [km/h]';
            app.VskmhSlider = uislider(app.PacejkaTireModelUIFigure);
            app.VskmhSlider.Limits = [0 300];
            app.P0psiSliderLabel = uilabel(app.PacejkaTireModelUIFigure);
            app.P0psiSliderLabel.Text = 'P0 [psi]';
            app.P0psiSlider = uislider(app.PacejkaTireModelUIFigure);
            app.P0psiSlider.Limits = [20 35];
            app.P0psiSlider.MajorTicks = [20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35];
            app.P0psiSlider.Value = 27.5;
            app.PrintParamatersButton = uibutton(app.PacejkaTireModelUIFigure, 'push');
            app.PrintParamatersButton.ButtonPushedFcn = createCallbackFcn(app, @PrintParamatersButtonPushed, true);
            app.PrintParamatersButton.Text = 'Print Paramaters';
            app.SaveModelButton = uibutton(app.PacejkaTireModelUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pacejka_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PacejkaTireModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PacejkaTireModelUIFigure)
        end
    end
end